classdef P1_7Equipo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OperacionesBsicasTab          matlab.ui.container.Tab
        DeshacerButton                matlab.ui.control.Button
        CargarImagenButton            matlab.ui.control.Button
        LabelInfoProcesada            matlab.ui.control.Label
        LabelInfoOriginal             matlab.ui.control.Label
        AnlisisdepxelesPanel          matlab.ui.container.Panel
        CalcularDistanciasButton      matlab.ui.control.Button
        OperacionesdeintensidadPanel  matlab.ui.container.Panel
        SustraccinButton              matlab.ui.control.Button
        AdicinButton                  matlab.ui.control.Button
        InversinBinariaButton         matlab.ui.control.Button
        InversinFotogrficaButton      matlab.ui.control.Button
        TransformacionesgeomtricasPanel  matlab.ui.container.Panel
        EspejoHorizontalButton        matlab.ui.control.Button
        RotarImagenButton             matlab.ui.control.Button
        DesplazarImagenButton         matlab.ui.control.Button
        ModificarTamaoButton          matlab.ui.control.Button
        UIAxesProcesada               matlab.ui.control.UIAxes
        UIAxesOriginal                matlab.ui.control.UIAxes
        EcualizacinTab                matlab.ui.container.Tab
        UmbralizacinTab               matlab.ui.container.Tab
    end

    
    properties (Access = private)
    ImagenOriginal
    ImagenProcesada
    HistorialOperaciones = {}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModificarTamaoButton
        function ModificarTamanioButtonPushed(app, event)
   if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar nuevo tamaño al usuario
    prompt = {'Nuevo ancho:', 'Nuevo alto:'};
    dlgtitle = 'Modificar tamaño de imagen';
    dims = [1 35];
    definput = {num2str(size(app.ImagenOriginal,2)), num2str(size(app.ImagenOriginal,1))};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    nuevoAncho = str2double(answer{1});
    nuevoAlto = str2double(answer{2});

    if isnan(nuevoAncho) || isnan(nuevoAlto) || nuevoAncho <= 0 || nuevoAlto <= 0
        uialert(app.UIFigure, 'Valores inválidos. Ingresa números positivos.', 'Error');
        return;
    end

   
    % Redimensionar imagen
    imagenNueva = imresize(app.ImagenOriginal, [nuevoAlto nuevoAncho]);

    % Verificar si la imagen realmente cambió
    if isempty(app.ImagenProcesada) || ~isequal(imagenNueva, app.ImagenProcesada)
        app.ImagenProcesada = imagenNueva;

        % Mostrar imagen procesada
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

        % Mostrar características
        infoProcesada = sprintf('Imagen procesada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
        app.LabelInfoProcesada.Text = infoProcesada;

        % Guardar en historial
        app.HistorialOperaciones{end+1} = app.ImagenProcesada;
    end

        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona una imagen');
    if isequal(file, 0)
        return; % El usuario canceló
    end

    imgPath = fullfile(path, file);
    img = imread(imgPath);
    app.ImagenOriginal = img;

    % Mostrar imagen original
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal);

    % Mostrar características de la imagen original
    infoOriginal = sprintf('Imagen original: %d x %d', size(app.ImagenOriginal,1), size(app.ImagenOriginal,2));
    app.LabelInfoOriginal.Text = infoOriginal;

    % Limpiar imagen procesada y su información
    cla(app.UIAxesProcesada);
    app.LabelInfoProcesada.Text = 'Imagen procesada: -';

    % Reiniciar historial
    app.HistorialOperaciones = {};
    
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
    if isempty(app.HistorialOperaciones)
        uialert(app.UIFigure, 'No hay operaciones para deshacer.', 'Aviso');
        return;
    end

    % Eliminar la última operación
    app.HistorialOperaciones(end) = [];

    if isempty(app.HistorialOperaciones)
        % Si no hay más historial, volver a la imagen original
        app.ImagenProcesada = app.ImagenOriginal;
    else
        % Restaurar la imagen anterior
        app.ImagenProcesada = app.HistorialOperaciones{end};
    end

    % Mostrar imagen procesada actualizada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Actualizar etiqueta de características
    infoProcesada = sprintf('Imagen procesada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

        end

        % Button pushed function: InversinFotogrficaButton
        function InvertirFotograficaButtonPushed(app, event)

% Verificar si hay una imagen cargada
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Invertir la imagen (funciona para RGB y escala de grises)
    app.ImagenProcesada = 255 - app.ImagenOriginal;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características de la imagen procesada
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial para deshacer
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;

        end

        % Button pushed function: InversinBinariaButton
        function vertirBinariaButtonPushed(app, event)

   if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Convertir a escala de grises si es RGB
    if size(app.ImagenOriginal, 3) == 3
        imagenGris = rgb2gray(app.ImagenOriginal);
    else
        imagenGris = app.ImagenOriginal;
    end

    % Convertir a binaria usando un umbral (por ejemplo, 128)
    imagenBinaria = imbinarize(imagenGris, 128/255);

    % Invertir la imagen binaria
    imagenInvertida = ~imagenBinaria;

    % Convertir a formato uint8 (0 y 255)
    app.ImagenProcesada = uint8(imagenInvertida) * 255;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OperacionesBsicasTab = uitab(app.TabGroup);
            app.OperacionesBsicasTab.Title = ' Operaciones Básicas';
            app.UIAxesOriginal = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesOriginal, 'Imagen Original')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesProcesada = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesProcesada, 'Imagen Procesada')
            app.UIAxesProcesada.XTick = [];
            app.UIAxesProcesada.YTick = [];
            app.TransformacionesgeomtricasPanel = uipanel(app.OperacionesBsicasTab);
            app.TransformacionesgeomtricasPanel.Title = ' Transformaciones geométricas';
            app.ModificarTamaoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.ModificarTamaoButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarTamanioButtonPushed, true);
            app.ModificarTamaoButton.Text = ' Modificar Tamaño';
            app.DesplazarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.DesplazarImagenButton.Text = 'Desplazar Imagen';
            app.RotarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.RotarImagenButton.Text = 'Rotar Imagen';
            app.EspejoHorizontalButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.EspejoHorizontalButton.Text = 'Espejo Horizontal';
            app.OperacionesdeintensidadPanel = uipanel(app.OperacionesBsicasTab);
            app.OperacionesdeintensidadPanel.Title = 'Operaciones de intensidad';
            app.InversinFotogrficaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinFotogrficaButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirFotograficaButtonPushed, true);
            app.InversinFotogrficaButton.Text = 'Inversión Fotográfica';
            app.InversinBinariaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinBinariaButton.ButtonPushedFcn = createCallbackFcn(app, @vertirBinariaButtonPushed, true);
            app.InversinBinariaButton.Text = 'Inversión Binaria';
            app.AdicinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.AdicinButton.Text = 'Adición';
            app.SustraccinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.SustraccinButton.Text = 'Sustracción';
            app.AnlisisdepxelesPanel = uipanel(app.OperacionesBsicasTab);
            app.AnlisisdepxelesPanel.Title = 'Análisis de píxeles';
            app.CalcularDistanciasButton = uibutton(app.AnlisisdepxelesPanel, 'push');
            app.CalcularDistanciasButton.Text = 'Calcular Distancias';
            app.LabelInfoOriginal = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoOriginal.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoProcesada.Text = 'LabelInfoProcesada';
            app.CargarImagenButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.DeshacerButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer';
            app.EcualizacinTab = uitab(app.TabGroup);
            app.EcualizacinTab.Title = 'Ecualización';
            app.UmbralizacinTab = uitab(app.TabGroup);
            app.UmbralizacinTab.Title = 'Umbralización';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1_7Equipo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end